classdef Dashboard < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SimulationControlPanel       matlab.ui.container.Panel
        ReadyLabel                   matlab.ui.control.Label
        StatusLamp                   matlab.ui.control.Lamp
        StatusLampLabel              matlab.ui.control.Label
        SettingsButton               matlab.ui.control.Button
        ExportResultsButton          matlab.ui.control.Button
        ResetButton                  matlab.ui.control.Button
        PauseButton                  matlab.ui.control.Button
        RunSimulationButton          matlab.ui.control.Button
        GenerateScenarioButton       matlab.ui.control.Button
        SARRSSTUDIOPanel             matlab.ui.container.Panel
        SelfAwareReconfigurableRadarReceiverEvaluationPlatformLabel  matlab.ui.control.Label
        AIDecisionPanel              matlab.ui.container.Panel
        AIDecisionTextArea           matlab.ui.control.TextArea
        AIDecisionTextAreaLabel      matlab.ui.control.Label
        SignalAnalysisPanel          matlab.ui.container.Panel
        SignalAxes                   matlab.ui.control.UIAxes
        TargetPanel                  matlab.ui.container.Panel
        mLabel_2                     matlab.ui.control.Label
        RCSEditField                 matlab.ui.control.NumericEditField
        RCSLabel                     matlab.ui.control.Label
        AltitudeLabel                matlab.ui.control.Label
        AltitudeEditField            matlab.ui.control.NumericEditField
        mLabel                       matlab.ui.control.Label
        HeadingDropDown              matlab.ui.control.DropDown
        HeadingLabel                 matlab.ui.control.Label
        msLabel_2                    matlab.ui.control.Label
        VelocityEditField            matlab.ui.control.NumericEditField
        VelocityLabel                matlab.ui.control.Label
        kmLabel                      matlab.ui.control.Label
        InitialRangeLabel            matlab.ui.control.Label
        RangeEditField               matlab.ui.control.NumericEditField
        TargetTypeDropDown           matlab.ui.control.DropDown
        TargetTypeLabel              matlab.ui.control.Label
        TargetsLabel                 matlab.ui.control.Label
        TargetCountDropDown          matlab.ui.control.DropDown
        ReceiverStatusPanel          matlab.ui.container.Panel
        ReceiverStatusTextArea       matlab.ui.control.TextArea
        ReceiverStatusTextAreaLabel  matlab.ui.control.Label
        RadarDisplayPanel            matlab.ui.container.Panel
        RadarAxes                    matlab.ui.control.UIAxes
        EnvironmentPanel             matlab.ui.container.Panel
        GridLayout                   matlab.ui.container.GridLayout
        TemperatureEditField         matlab.ui.control.NumericEditField
        Label                        matlab.ui.control.Label
        JammingDropDown              matlab.ui.control.DropDown
        JammingLabel                 matlab.ui.control.Label
        msLabel                      matlab.ui.control.Label
        WindSpeedEditField           matlab.ui.control.NumericEditField
        WindLabel                    matlab.ui.control.Label
        TerrainDropDown              matlab.ui.control.DropDown
        TerrainLabel                 matlab.ui.control.Label
        FogLabel                     matlab.ui.control.Label
        RainLabel                    matlab.ui.control.Label
        FogDropDown                  matlab.ui.control.DropDown
        RainDropDown                 matlab.ui.control.DropDown
        Label_2                      matlab.ui.control.Label
        HumidityEditField            matlab.ui.control.NumericEditField
        CLabel                       matlab.ui.control.Label
        HumidityLabel                matlab.ui.control.Label
        TemperatureLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        Simulation
        Environment
        Target
        Receiver
        Signal
        Results 
        Radar
        Waveform
        Echo
        Matched
        Detection
        Track
        AI
        ReceiverControl
        CFARResult
        Range
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)

clc

fprintf('\n');
fprintf('=========================================\n');
fprintf('         SARRS STUDIO\n');
fprintf('=========================================\n');

%% ====================================================
% READ ENVIRONMENT
%% ====================================================

app.Environment.Temperature = app.TemperatureEditField.Value;
app.Environment.Humidity    = app.HumidityEditField.Value;
app.Environment.Rain        = app.RainDropDown.Value;
app.Environment.Fog         = app.FogDropDown.Value;
app.Environment.Terrain     = app.TerrainDropDown.Value;
app.Environment.Wind        = app.WindSpeedEditField.Value;
app.Environment.Jamming     = app.JammingDropDown.Value;

%% Rain Loss

switch app.Environment.Rain

    case 'None'
        app.Environment.RainLoss = 0;

    case 'Light'
        app.Environment.RainLoss = 1;

    case 'Moderate'
        app.Environment.RainLoss = 3;

    case 'Heavy'
        app.Environment.RainLoss = 6;

end

%% Fog Loss

switch app.Environment.Fog

    case 'None'
        app.Environment.FogLoss = 0;

    case 'Light'
        app.Environment.FogLoss = 0.2;

    case 'Medium'
        app.Environment.FogLoss = 0.5;

    case 'Dense'
        app.Environment.FogLoss = 1;

end

%% ====================================================
% READ TARGET
%% ====================================================

app.Target.Count     = str2double(app.TargetCountDropDown.Value);
app.Target.Type      = app.TargetTypeDropDown.Value;
app.Target.Range     = app.RangeEditField.Value;
app.Target.Velocity  = app.VelocityEditField.Value;
app.Target.Heading   = app.HeadingDropDown.Value;
app.Target.Altitude  = app.AltitudeEditField.Value;
app.Target.RCS       = app.RCSEditField.Value;

%% ====================================================
% TARGET MOTION MODEL
%% ====================================================

app.Target = Target(app.Target);

%% ====================================================
% RADAR MODEL
%% ====================================================

app.Radar = Radar();
%% ====================================================
% Waveform  MODEL
%% ====================================================
app.Waveform = Waveform(app.Radar);

%% ====================================================
% Echo  MODEL
%% ====================================================
app.Echo = Echo( ...
    app.Target,...
    app.Radar,...
    app.Waveform);

app.Receiver = Receiver(app.Echo);


app.Matched = MatchedFilter( ...
    app.Receiver,...
    app.Waveform);


app.CFARResult = CFARDetector(app.Matched);

disp(' ')
disp('========== CFAR ==========')
disp(app.CFARResult)

fprintf('\n');
fprintf('Matched Peak = %e\n',app.Matched.Peak);

if isempty(app.CFARResult.Peaks)

    fprintf('No CFAR Peaks Found\n');

else

    fprintf('CFAR Peaks:\n');
    disp(app.CFARResult.Peaks)

end

fprintf('Detected Targets = %d\n', ...
    app.CFARResult.NumberOfTargets);
app.Range = RangeEstimator( ...
    app.CFARResult,...
    app.Radar,...
    app.Waveform);

app.Detection = Detection(app.CFARResult);

if app.Detection.TargetDetected

   %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% Tracking
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

TrackTemp = repmat(Tracking(app.Target(1),app.Detection),1,length(app.Target));

for i = 1:length(app.Target)

    TrackTemp(i) = Tracking( ...
        app.Target(i), ...
        app.Detection);

end

app.Track = TrackTemp;

    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    % AI
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% AI
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

AITemp = repmat(AI(app.Track(1)),1,length(app.Track));

for i = 1:length(app.Track)

    AITemp(i) = AI(app.Track(i));

end

app.AI = AITemp;

txt = {};

for i = 1:length(app.AI)

    txt{end+1} = ['Target ' num2str(i)];
    txt{end+1} = ['Type : ' char(app.AI(i).TargetType)];
    txt{end+1} = ['Range : ' ...
        num2str(app.AI(i).Range/1000,'%.1f') ' km'];
    txt{end+1} = ['Threat : ' char(app.AI(i).ThreatLevel)];
    txt{end+1} = ['Action : ' char(app.AI(i).Action)];
    txt{end+1} = ' ';

end

app.AIDecisionTextArea.Value = txt;
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    % Cognitive Receiver
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

    app.ReceiverControl = ...
        CognitiveReceiver( ...
        app.Environment,...
        app.AI);

    app.ReceiverStatusTextArea.Value = {

        ['Mode : ' ...
        char(app.ReceiverControl.Mode)]

        ['LNA Gain : ' ...
        num2str(app.ReceiverControl.LNAGain) ...
        ' dB']

        ['IF Gain : ' ...
        num2str(app.ReceiverControl.IFGain) ...
        ' dB']

        ['Bandwidth : ' ...
        num2str(app.ReceiverControl.Bandwidth/1e6) ...
        ' MHz']

        ['AGC : ' ...
        char(app.ReceiverControl.AGC)]

        ['Filter : ' ...
        char(app.ReceiverControl.Filter)]

    };

else

    app.Track = [];

    app.AI = [];

    app.ReceiverControl = [];

    app.AIDecisionTextArea.Value = {

        'NO TARGET DETECTED'

    };

    app.ReceiverStatusTextArea.Value = {

        'Receiver Idle'

    };

end
%% ====================================================
% DISPLAY
%% ====================================================

disp(' ')
disp('========== ENVIRONMENT ==========')
disp(app.Environment)

disp(' ')
disp('========== TARGET ==========')

for i = 1:length(app.Target)

    fprintf('\nTarget %d\n',i);

    disp(app.Target(i));

end
disp(' ')
disp('========== RADAR ==========')
disp(app.Radar)

disp(' ')
disp('========== WAVEFORM ==========')
disp(app.Waveform)

disp(' ')
disp('========== ECHO ==========')
disp(app.Echo)

disp(' ')
disp('========== RECEIVER ==========')
disp(app.Receiver)

disp(' ')
disp('========== MATCHED FILTER ==========')
disp(app.Matched)

disp(' ')
disp('========== DETECTION ==========')
disp(app.Detection)

if app.Detection.TargetDetected

    disp(' ')
    disp('========== TRACK ==========')
    disp(app.Track)

    disp(' ')
    disp('========== AI DECISION ==========')
    disp(app.AI)

    disp(' ')
    disp('========== RECEIVER CONTROL ==========')
    disp(app.ReceiverControl)

else

    disp(' ')
    disp('NO TARGET DETECTED')

end

disp(' ')
disp('========== RANGE ESTIMATION ==========')

for i = 1:length(app.Range)

    fprintf('Target %d\n',i);

    fprintf(' Sample : %d\n',app.Range(i).Sample);

    fprintf(' Delay  : %.6e s\n',app.Range(i).Delay);

    fprintf(' Range  : %.2f km\n\n',...
        app.Range(i).Distance/1000);

end
%% ====================================================
% PLOT
%% ====================================================

figure(1)
clf

figure(1)
clf

hold on

colors = lines(length(app.Target));

for i = 1:length(app.Target)

    plot( ...
        app.Target(i).Time,...
        app.Target(i).Distance/1000,...
        'Color',colors(i,:),...
        'LineWidth',2);

end

grid on

xlabel('Time (seconds)')
ylabel('Distance (km)')
title('Target Distance vs Time')

legendStrings = cell(1,length(app.Target));

for i = 1:length(app.Target)

    legendStrings{i} = ['Target ' num2str(i)];

end

legend(legendStrings)

grid on

xlabel('Time (seconds)')
ylabel('Distance (km)')
title('Target Distance vs Time')

fprintf('\n');
fprintf('=========================================\n');
fprintf('Simulation Completed\n');
fprintf('=========================================\n');

figure(2)
clf

plot(app.Waveform.Time*1e6,...
     app.Waveform.Signal,...
     'b','LineWidth',2)

hold on

plot(app.Waveform.Time*1e6,...
     app.Echo.Signal,...
     'r','LineWidth',2)

grid on

xlabel('Time (\mus)')
ylabel('Amplitude')

legend('Transmit','Echo')

title('Transmit and Echo Pulse')

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% Plot Receiver Output in App Designer
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
cla(app.SignalAxes)

plot(app.SignalAxes, app.Matched.Output,'b','LineWidth',1.5)

grid(app.SignalAxes,'on')

title(app.SignalAxes,'Matched Filter Output')

xlabel(app.SignalAxes,'Samples')

ylabel(app.SignalAxes,'Amplitude')
cla(app.RadarAxes)

if app.Detection.TargetDetected

    cla(app.RadarAxes)

   cla(app.RadarAxes)

hold(app.RadarAxes,'on')

colors = lines(length(app.Track));

for i = 1:length(app.Track)

    plot(app.RadarAxes,...
        app.Track(i).HistoryX/1000,...
        app.Track(i).HistoryY/1000,...
        'Color',colors(i,:),...
        'LineWidth',2);

    plot(app.RadarAxes,...
        app.Track(i).HistoryX(end)/1000,...
        app.Track(i).HistoryY(end)/1000,...
        'o',...
        'MarkerFaceColor',colors(i,:),...
        'MarkerEdgeColor',colors(i,:));

end

grid(app.RadarAxes,'on')

xlabel(app.RadarAxes,'X (km)')
ylabel(app.RadarAxes,'Y (km)')
title(app.RadarAxes,'Multi Target Tracking')


else

    cla(app.RadarAxes)

    title(app.RadarAxes,'No Target')

end


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.EnvironmentPanel = uipanel(app.UIFigure);
            app.EnvironmentPanel.Title = 'Environment';
            app.GridLayout = uigridlayout(app.EnvironmentPanel);
            app.TemperatureLabel = uilabel(app.GridLayout);
            app.TemperatureLabel.Text = 'Temperature';
            app.HumidityLabel = uilabel(app.GridLayout);
            app.HumidityLabel.Text = 'Humidity  ';
            app.CLabel = uilabel(app.GridLayout);
            app.CLabel.Text = '°C';
            app.HumidityEditField = uieditfield(app.GridLayout, 'numeric');
            app.HumidityEditField.Value = 60;
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = '%';
            app.RainDropDown = uidropdown(app.GridLayout);
            app.RainDropDown.Items = {'None', 'Light', 'Moderate', 'Heavy'};
            app.RainDropDown.Value = 'None';
            app.FogDropDown = uidropdown(app.GridLayout);
            app.FogDropDown.Items = {'None', 'Light', 'Medium', 'Dense'};
            app.FogDropDown.Value = 'None';
            app.RainLabel = uilabel(app.GridLayout);
            app.RainLabel.Text = 'Rain';
            app.FogLabel = uilabel(app.GridLayout);
            app.FogLabel.Text = 'Fog';
            app.TerrainLabel = uilabel(app.GridLayout);
            app.TerrainLabel.Text = 'Terrain';
            app.TerrainDropDown = uidropdown(app.GridLayout);
            app.TerrainDropDown.Items = {'Open Field', 'Urban', 'Mountain', 'Forest', 'Sea'};
            app.TerrainDropDown.Value = 'Open Field';
            app.WindLabel = uilabel(app.GridLayout);
            app.WindLabel.Text = 'Wind';
            app.WindSpeedEditField = uieditfield(app.GridLayout, 'numeric');
            app.WindSpeedEditField.Value = 10;
            app.msLabel = uilabel(app.GridLayout);
            app.msLabel.Text = 'm/s';
            app.JammingLabel = uilabel(app.GridLayout);
            app.JammingLabel.Text = 'Jamming';
            app.JammingDropDown = uidropdown(app.GridLayout);
            app.JammingDropDown.Items = {'OFF', 'Noise Jammer', 'Spot Jammer', 'Barrage Jammer', 'DRFM Jammer'};
            app.JammingDropDown.Value = 'OFF';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            app.TemperatureEditField = uieditfield(app.GridLayout, 'numeric');
            app.TemperatureEditField.Value = 30;
            app.RadarDisplayPanel = uipanel(app.UIFigure);
            app.RadarDisplayPanel.Title = 'Radar Display';
            app.RadarAxes = uiaxes(app.RadarDisplayPanel);
            title(app.RadarAxes, 'RadarAxes')
            xlabel(app.RadarAxes, 'X')
            ylabel(app.RadarAxes, 'Y')
            zlabel(app.RadarAxes, 'Z')
            subtitle(app.RadarAxes, 'RadarAxes')
            app.ReceiverStatusPanel = uipanel(app.UIFigure);
            app.ReceiverStatusPanel.Title = 'Receiver Status';
            app.ReceiverStatusTextAreaLabel = uilabel(app.ReceiverStatusPanel);
            app.ReceiverStatusTextAreaLabel.Text = 'Receiver Status';
            app.ReceiverStatusTextArea = uitextarea(app.ReceiverStatusPanel);
            app.TargetPanel = uipanel(app.UIFigure);
            app.TargetPanel.Title = 'Target';
            app.TargetCountDropDown = uidropdown(app.TargetPanel);
            app.TargetCountDropDown.Items = {'1', '2', '3', '4', '5'};
            app.TargetCountDropDown.Value = '1';
            app.TargetsLabel = uilabel(app.TargetPanel);
            app.TargetsLabel.Text = 'Targets';
            app.TargetTypeLabel = uilabel(app.TargetPanel);
            app.TargetTypeLabel.Text = 'Target Type';
            app.TargetTypeDropDown = uidropdown(app.TargetPanel);
            app.TargetTypeDropDown.Items = {'Aircraft', '', 'Drone', '', 'Missile', '', 'Helicopter', '', 'UAV'};
            app.TargetTypeDropDown.Value = 'Aircraft';
            app.RangeEditField = uieditfield(app.TargetPanel, 'numeric');
            app.RangeEditField.Value = 80;
            app.InitialRangeLabel = uilabel(app.TargetPanel);
            app.InitialRangeLabel.Text = 'Initial Range';
            app.kmLabel = uilabel(app.TargetPanel);
            app.kmLabel.Text = 'km';
            app.VelocityLabel = uilabel(app.TargetPanel);
            app.VelocityLabel.Text = 'Velocity';
            app.VelocityEditField = uieditfield(app.TargetPanel, 'numeric');
            app.VelocityEditField.Value = 250;
            app.msLabel_2 = uilabel(app.TargetPanel);
            app.msLabel_2.Text = 'm/s';
            app.HeadingLabel = uilabel(app.TargetPanel);
            app.HeadingLabel.Text = 'Heading';
            app.HeadingDropDown = uidropdown(app.TargetPanel);
            app.HeadingDropDown.Items = {'Toward Radar', '', 'Away from Radar', '', 'Left', '', 'Right'};
            app.HeadingDropDown.Value = 'Toward Radar';
            app.mLabel = uilabel(app.TargetPanel);
            app.mLabel.Text = 'm';
            app.AltitudeEditField = uieditfield(app.TargetPanel, 'numeric');
            app.AltitudeEditField.Value = 5000;
            app.AltitudeLabel = uilabel(app.TargetPanel);
            app.AltitudeLabel.Text = 'Altitude';
            app.RCSLabel = uilabel(app.TargetPanel);
            app.RCSLabel.Text = 'R C S';
            app.RCSEditField = uieditfield(app.TargetPanel, 'numeric');
            app.RCSEditField.Value = 10;
            app.mLabel_2 = uilabel(app.TargetPanel);
            app.mLabel_2.Text = 'm²';
            app.SignalAnalysisPanel = uipanel(app.UIFigure);
            app.SignalAnalysisPanel.Title = 'Signal Analysis';
            app.SignalAxes = uiaxes(app.SignalAnalysisPanel);
            title(app.SignalAxes, 'SignalAxes')
            xlabel(app.SignalAxes, 'X')
            ylabel(app.SignalAxes, 'Y')
            zlabel(app.SignalAxes, 'Z')
            app.AIDecisionPanel = uipanel(app.UIFigure);
            app.AIDecisionPanel.Title = 'AI Decision';
            app.AIDecisionTextAreaLabel = uilabel(app.AIDecisionPanel);
            app.AIDecisionTextAreaLabel.Text = 'AIDecisionTextArea';
            app.AIDecisionTextArea = uitextarea(app.AIDecisionPanel);
            app.SARRSSTUDIOPanel = uipanel(app.UIFigure);
            app.SARRSSTUDIOPanel.Title = 'SARRS STUDIO';
            app.SelfAwareReconfigurableRadarReceiverEvaluationPlatformLabel = uilabel(app.SARRSSTUDIOPanel);
            app.SelfAwareReconfigurableRadarReceiverEvaluationPlatformLabel.Text = 'Self-Aware Reconfigurable Radar Receiver Evaluation Platform';
            app.SimulationControlPanel = uipanel(app.UIFigure);
            app.SimulationControlPanel.Title = 'Simulation Control';
            app.GenerateScenarioButton = uibutton(app.SimulationControlPanel, 'push');
            app.GenerateScenarioButton.Text = 'Generate Scenario';
            app.RunSimulationButton = uibutton(app.SimulationControlPanel, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.PauseButton = uibutton(app.SimulationControlPanel, 'push');
            app.PauseButton.Text = 'Pause';
            app.ResetButton = uibutton(app.SimulationControlPanel, 'push');
            app.ResetButton.Text = 'Reset';
            app.ExportResultsButton = uibutton(app.SimulationControlPanel, 'push');
            app.ExportResultsButton.Text = 'Export Results';
            app.SettingsButton = uibutton(app.SimulationControlPanel, 'push');
            app.SettingsButton.Text = 'Settings';
            app.StatusLampLabel = uilabel(app.SimulationControlPanel);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.SimulationControlPanel);
            app.ReadyLabel = uilabel(app.SimulationControlPanel);
            app.ReadyLabel.Text = 'Ready';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Dashboard

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end